classdef ProyectoEstadisticaII < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DistribucionNormalPanel         matlab.ui.container.Panel
        SegundoValorDistribucionEditField  matlab.ui.control.NumericEditField
        SegundovalorEditFieldLabel      matlab.ui.control.Label
        ResultadodeProbabilidadLabel    matlab.ui.control.Label
        GenerarCurvadeDistribucinButton  matlab.ui.control.Button
        TipodedistribucionNormalButtonGroup  matlab.ui.container.ButtonGroup
        PaXbButton                      matlab.ui.control.RadioButton
        PXxButton_2                     matlab.ui.control.RadioButton
        PXxButton                       matlab.ui.control.RadioButton
        ValorEstadsticoXEditField       matlab.ui.control.NumericEditField
        ValorEstadsticoXEditFieldLabel  matlab.ui.control.Label
        DesviacinEstndardelaDistribucinEditField  matlab.ui.control.NumericEditField
        DesviacinEstndardelaDistribucinEditFieldLabel  matlab.ui.control.Label
        MediadeladistribucinNormalEditField  matlab.ui.control.NumericEditField
        MediadeladistribucinNormalEditFieldLabel  matlab.ui.control.Label
        AnlisisdeDistribucinNormalLabel  matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        ProbabilidadesPanel             matlab.ui.container.Panel
        SeleccionelaVariableProbabilsticaDropDown  matlab.ui.control.DropDown
        SeleccionarPreguntaLabel        matlab.ui.control.Label
        ResultadoLabel                  matlab.ui.control.Label
        CalcularButton_2                matlab.ui.control.Button
        RespuestaProbabilidadDropDown   matlab.ui.control.DropDown
        SeleccionelaVariableProbabilsticaDropDownLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ClculodeProbabilidadesLabel     matlab.ui.control.Label
        GraficasPanel                   matlab.ui.container.Panel
        SeleccionarVariableDropDown_3   matlab.ui.control.DropDown
        SeleccionarVariableDropDown_3Label  matlab.ui.control.Label
        GenerarGraficaButton            matlab.ui.control.Button
        DropDown2                       matlab.ui.control.DropDown
        TipodegraficaLabel              matlab.ui.control.Label
        GeneraciondeGraficasLabel       matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        EstadisticasPanel               matlab.ui.container.Panel
        CalcularButton                  matlab.ui.control.Button
        SeleccionarVariableDropDown     matlab.ui.control.DropDown
        SeleccionarVariableDropDown_2Label  matlab.ui.control.Label
        PanelResultados                 matlab.ui.container.Panel
        MnimoLabel                      matlab.ui.control.Label
        MximoLabel                      matlab.ui.control.Label
        VarianzaLabel                   matlab.ui.control.Label
        DesviacinestndarLabel           matlab.ui.control.Label
        ModaLabel                       matlab.ui.control.Label
        MedianaLabel                    matlab.ui.control.Label
        MediaLabel                      matlab.ui.control.Label
        AnlisisEstadsticoLabel          matlab.ui.control.Label
        EncuestaPanel                   matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        LimpiarButton                   matlab.ui.control.Button
        CargarExcelButton               matlab.ui.control.Button
        DatosdeEncuestaLabel            matlab.ui.control.Label
        PanelMenu                       matlab.ui.container.Panel
        DistribucinNormalButton         matlab.ui.control.Button
        ProbabilidadesButton            matlab.ui.control.Button
        GraficasButton                  matlab.ui.control.Button
        EstadisticasButton              matlab.ui.control.Button
        EncuestaButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        datosEncuesta

        variablesNumericas
    
        variablesCategoricas
    end
    
    methods (Access = private)

        function edadesNumericas = convertirEdades(app, edadesTexto)

            edadesNumericas = zeros(length(edadesTexto),1);
        
            for i = 1:length(edadesTexto)
        
                edad = string(edadesTexto{i});
        
                switch edad
        
                    case "18-20"
                        edadesNumericas(i) = 19;
        
                    case "21-25"
                        edadesNumericas(i) = 23;
        
                    case "26-30"
                        edadesNumericas(i) = 28;
        
                    otherwise
                        edadesNumericas(i) = 35;
        
                end
        
            end
        end
        
       function clasificarVariables(app)

            nombresVariables = ...
            app.datosEncuesta.Properties.VariableNames;
        
            % Todas serán categóricas inicialmente
            app.variablesCategoricas = nombresVariables;
        
            % Dropdown estadísticas
            app.SeleccionarVariableDropDown.Items = ...
            nombresVariables;
        
            % Dropdown gráficas
            app.SeleccionarVariableDropDown_3.Items = ...
            nombresVariables;
        
            % Dropdown probabilidades
            app.SeleccionelaVariableProbabilsticaDropDown.Items = ...
            nombresVariables;
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

             % Mostrar encuesta inicialmente
            app.EncuestaPanel.Visible = 'on';
        
            % Ocultar otros paneles
            app.EstadisticasPanel.Visible = 'off';
        
            app.GraficasPanel.Visible = 'off';
        
            app.ProbabilidadesPanel.Visible = 'off';
        
            app.DistribucionNormalPanel.Visible = 'off';

        end

        % Button pushed function: EncuestaButton
        function EncuestaButtonPushed(app, event)
             app.EncuestaPanel.Visible = 'on';
            
             app.GraficasPanel.Visible = 'off';
            
             app.EstadisticasPanel.Visible = 'off';
            
             app.ProbabilidadesPanel.Visible = 'off';
            
             app.DistribucionNormalPanel.Visible = 'off';

        end

        % Button pushed function: EstadisticasButton
        function EstadisticasButtonPushed(app, event)
            app.EstadisticasPanel.Visible ="on";
            app.GraficasPanel.Visible = "off";
            app.ProbabilidadesPanel.Visible = "off";
            app.DistribucionNormalPanel.Visible ="off";
            app.EncuestaPanel.Visible = "off";
        end

        % Button pushed function: CargarExcelButton
        function CargarExcelButtonPushed(app, event)
                        % Seleccionar archivo
            [archivo, ruta] = uigetfile('*.xlsx');
        
            % Verificar cancelación
            if isequal(archivo,0)
        
                return;
        
            end
        
            % Ruta completa
            rutaCompleta = fullfile(ruta, archivo);
        
            % Leer Excel
            app.datosEncuesta = readtable(rutaCompleta);
        
            % Mostrar tabla
            app.UITable.Data = ...
            app.datosEncuesta;
        
            % Total encuestas
            total = height(app.datosEncuesta);
        
            app.DatosdeEncuestaLabel.Text = ...
            string(total);
        
            % Clasificar variables
            clasificarVariables(app);

        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            variable = ...
            app.SeleccionarVariableDropDown.Value;
        
            datos = app.datosEncuesta.(variable);
        
            % Convertir a categórico
            categorias = categorical(datos);
        
            % Frecuencias
            conteos = countcats(categorias);
        
            nombres = categories(categorias);
        
            % Moda
            [maximo, indice] = max(conteos);
        
            modaCategoria = nombres{indice};
        
            total = sum(conteos);
        
            porcentaje = (maximo / total) * 100;
        
            % Mostrar resultados
            app.MediaLabel.Text = ...
            ['Respuesta más frecuente: ', modaCategoria];
        
            app.MedianaLabel.Text = ...
            ['Frecuencia: ', num2str(maximo)];
        
            app.ModaLabel.Text = ...
            ['Porcentaje: ', num2str(porcentaje), '%'];
        
            % Limpiar labels que ya no usaremos
            app.DesviacinestndarLabel.Text = '';
            app.VarianzaLabel.Text = '';
            app.MximoLabel.Text = '';
            app.MnimoLabel.Text = '';
        end

        % Button pushed function: GenerarGraficaButton
        function GenerarGraficaButtonPushed(app, event)
              % Validar datos
            if isempty(app.datosEncuesta)
        
                uialert(app.UIFigure,...
                'Debe cargar datos primero',...
                'Error');
        
                return;
        
            end
        
            % Tipo gráfica
            tipo = app.DropDown2.Value;
        
            % Variable
            variable = ...
            app.SeleccionarVariableDropDown_3.Value;
        
            % Datos
            datos = app.datosEncuesta.(variable);
        
            % Limpiar axes
            cla(app.UIAxes);
        
            switch tipo
        
                case 'Histograma'
        
                    if isnumeric(datos)
        
                        histogram(app.UIAxes, datos);
        
                    else
        
                        uialert(app.UIFigure,...
                        'El histograma requiere datos numéricos',...
                        'Error');
        
                        return;
        
                    end
        
                case 'Barras'
        
                    categorias = categorical(datos);
        
                    valores = countcats(categorias);
        
                    bar(app.UIAxes, valores);
        
                    xticklabels(app.UIAxes,...
                    categories(categorias));
        
                case 'Pastel'
        
                    categorias = categorical(datos);
        
                    valores = countcats(categorias);
        
                    pie(app.UIAxes, valores);
        
            end
        
            title(app.UIAxes, variable);
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
              app.UITable.Data = [];

              app.datosEncuesta = [];
            
              app.variablesNumericas = {};
            
              app.variablesCategoricas = {};
        end

        % Button pushed function: GraficasButton
        function GraficasButtonPushed(app, event)
                app.GraficasPanel.Visible = 'on';

                app.EstadisticasPanel.Visible = 'off';
            
                app.ProbabilidadesPanel.Visible = 'off';
            
                app.DistribucionNormalPanel.Visible = 'off';
            
                app.EncuestaPanel.Visible = 'off';
        end

        % Button pushed function: DistribucinNormalButton
        function DistribucinNormalButtonPushed(app, event)
                app.DistribucionNormalPanel.Visible = 'on';

                app.GraficasPanel.Visible = 'off';
            
                app.EstadisticasPanel.Visible = 'off';
            
                app.ProbabilidadesPanel.Visible = 'off';
            
                app.EncuestaPanel.Visible = 'off';
        end

        % Button pushed function: CalcularButton_2
        function CalcularButton_2Pushed(app, event)
            variable = ...
            app.SeleccionelaVariableProbabilsticaDropDown.Value;
        
            respuesta = ...
            app.RespuestaProbabilidadDropDown.Value;
        
            datos = string(app.datosEncuesta.(variable));
        
            total = length(datos);
        
            favorables = sum(datos == respuesta);
        
            probabilidad = favorables / total;
        
            porcentaje = probabilidad * 100;
        
            app.ResultadoLabel.Text = ...
            ['Probabilidad: ', ...
            num2str(probabilidad), ...
            ' (', ...
            num2str(porcentaje), '%)'];


        end

        % Button pushed function: GenerarCurvadeDistribucinButton
        function GenerarCurvadeDistribucinButtonPushed(app, event)
                        % Obtener valores
            media = ...
            app.MediadeladistribucinNormalEditField.Value;
        
            desviacion = ...
            app.DesviacinEstndardelaDistribucinEditField.Value;
        
            valorX = ...
            app.ValorEstadsticoXEditField.Value;
        
            % Validar desviación
            if desviacion <= 0
        
                uialert(app.UIFigure,...
                'La desviación estándar debe ser mayor que cero',...
                'Error');
        
                return;
        
            end
        
            % Generar eje X
            x = linspace(...
            media - 4*desviacion,...
            media + 4*desviacion,...
            1000);
        
            % PDF normal
            y = normpdf(x,media,desviacion);
        
            % Limpiar gráfica
            cla(app.UIAxes2);
        
            % Dibujar curva
            plot(app.UIAxes2,...
            x,y,...
            'LineWidth',2);
        
            hold(app.UIAxes2,'on');
        
            % MENOR QUE
            if app.PXxButton.Value
        
                areaX = x(x <= valorX);
        
                areaY = ...
                normpdf(areaX,media,desviacion);
        
                fill(app.UIAxes2,...
                areaX,...
                areaY,...
                'b');
        
                probabilidad = ...
                normcdf(valorX,media,desviacion);
        
            end
        
            % MAYOR QUE
            if app.PXxButton_2.Value
        
                areaX = x(x >= valorX);
        
                areaY = ...
                normpdf(areaX,media,desviacion);
        
                fill(app.UIAxes2,...
                areaX,...
                areaY,...
                'r');
        
                probabilidad = ...
                1 - normcdf(valorX,media,desviacion);
        
            end
            % ENTRE DOS VALORES
            if app.PaXbButton.Value
            
                valorB = ...
                app.SegundoValorDistribucionEditField.Value;
            
                areaX = x(x >= valorX & x <= valorB);
            
                areaY = ...
                normpdf(areaX,media,desviacion);
            
                fill(app.UIAxes2,...
                areaX,...
                areaY,...
                'g');
            
                probabilidad = ...
                normcdf(valorB,media,desviacion) - ...
                normcdf(valorX,media,desviacion);
            end

        
            hold(app.UIAxes2,'off');
        
            % Mostrar resultado
            app.ResultadodeProbabilidadLabel.Text = ...
            ['Probabilidad: ', num2str(probabilidad)];
        end

        % Button pushed function: ProbabilidadesButton
        function ProbabilidadesButtonPushed(app, event)
                app.GraficasPanel.Visible = 'off';

                app.EstadisticasPanel.Visible = 'off';
            
                app.ProbabilidadesPanel.Visible = 'on';
            
                app.DistribucionNormalPanel.Visible = 'off';
            
                app.EncuestaPanel.Visible = 'off';
        end

        % Value changed function: RespuestaProbabilidadDropDown
        function RespuestaProbabilidadDropDownValueChanged(app, event)
                variable = ...
                app.SeleccionelaVariableProbabilsticaDropDown.Value;
            
                datos = app.datosEncuesta.(variable);
            
                categorias = unique(string(datos));
            
                app.RespuestaProbabilidadDropDown.Items = ...
                cellstr(categorias);
            
        end

        % Selection changed function: TipodedistribucionNormalButtonGroup
        function TipodedistribucionNormalButtonGroupSelectionChanged(app, event)
            if app.PaXbButton.Value
        
                app.SegundoValorDistribucionEditField.Visible = 'on';
        
            else
        
                app.SegundoValorDistribucionEditField.Visible = 'off';
        
            end
            
        end

        % Value changed function: SeleccionelaVariableProbabilsticaDropDown
        function SeleccionelaVariableProbabilsticaDropDownValueChanged(app, event)
                % Obtener pregunta seleccionada
            variable = ...
            app.SeleccionelaVariableProbabilsticaDropDown.Value;
        
            % Obtener datos
            datos = app.datosEncuesta.(variable);
        
            % Convertir a texto
            datosTexto = string(datos);
        
            % Obtener respuestas únicas
            respuestas = unique(datosTexto);
        
            % Llenar dropdown respuestas
            app.RespuestaProbabilidadDropDown.Items = ...
            cellstr(respuestas);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PanelMenu = uipanel(app.UIFigure);
            app.PanelMenu.Title = 'PanelMenu';
            app.EncuestaButton = uibutton(app.PanelMenu, 'push');
            app.EncuestaButton.ButtonPushedFcn = createCallbackFcn(app, @EncuestaButtonPushed, true);
            app.EncuestaButton.Text = 'Encuesta';
            app.EstadisticasButton = uibutton(app.PanelMenu, 'push');
            app.EstadisticasButton.ButtonPushedFcn = createCallbackFcn(app, @EstadisticasButtonPushed, true);
            app.EstadisticasButton.Text = 'Estadisticas';
            app.GraficasButton = uibutton(app.PanelMenu, 'push');
            app.GraficasButton.ButtonPushedFcn = createCallbackFcn(app, @GraficasButtonPushed, true);
            app.GraficasButton.Text = 'Graficas';
            app.ProbabilidadesButton = uibutton(app.PanelMenu, 'push');
            app.ProbabilidadesButton.ButtonPushedFcn = createCallbackFcn(app, @ProbabilidadesButtonPushed, true);
            app.ProbabilidadesButton.Text = 'Probabilidades';
            app.DistribucinNormalButton = uibutton(app.PanelMenu, 'push');
            app.DistribucinNormalButton.ButtonPushedFcn = createCallbackFcn(app, @DistribucinNormalButtonPushed, true);
            app.DistribucinNormalButton.Text = 'Distribución Normal';
            app.EncuestaPanel = uipanel(app.UIFigure);
            app.EncuestaPanel.Title = 'Encuesta';
            app.DatosdeEncuestaLabel = uilabel(app.EncuestaPanel);
            app.DatosdeEncuestaLabel.Text = 'Datos de Encuesta';
            app.CargarExcelButton = uibutton(app.EncuestaPanel, 'push');
            app.CargarExcelButton.ButtonPushedFcn = createCallbackFcn(app, @CargarExcelButtonPushed, true);
            app.CargarExcelButton.Text = 'Cargar Excel';
            app.LimpiarButton = uibutton(app.EncuestaPanel, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.UITable = uitable(app.EncuestaPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.EstadisticasPanel = uipanel(app.UIFigure);
            app.EstadisticasPanel.Title = 'Estadisticas';
            app.AnlisisEstadsticoLabel = uilabel(app.EstadisticasPanel);
            app.AnlisisEstadsticoLabel.Text = 'Análisis Estadístico';
            app.PanelResultados = uipanel(app.EstadisticasPanel);
            app.PanelResultados.Title = 'PanelResultados';
            app.MediaLabel = uilabel(app.PanelResultados);
            app.MediaLabel.Text = 'Media:';
            app.MedianaLabel = uilabel(app.PanelResultados);
            app.MedianaLabel.Text = 'Mediana:';
            app.ModaLabel = uilabel(app.PanelResultados);
            app.ModaLabel.Text = 'Moda:';
            app.DesviacinestndarLabel = uilabel(app.PanelResultados);
            app.DesviacinestndarLabel.Text = 'Desviación estándar:';
            app.VarianzaLabel = uilabel(app.PanelResultados);
            app.VarianzaLabel.Text = 'Varianza:';
            app.MximoLabel = uilabel(app.PanelResultados);
            app.MximoLabel.Text = 'Máximo:';
            app.MnimoLabel = uilabel(app.PanelResultados);
            app.MnimoLabel.Text = 'Mínimo:';
            app.SeleccionarVariableDropDown_2Label = uilabel(app.EstadisticasPanel);
            app.SeleccionarVariableDropDown_2Label.Text = 'Seleccionar Variable:';
            app.SeleccionarVariableDropDown = uidropdown(app.EstadisticasPanel);
            app.CalcularButton = uibutton(app.EstadisticasPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.GraficasPanel = uipanel(app.UIFigure);
            app.GraficasPanel.Title = 'Graficas';
            app.UIAxes = uiaxes(app.GraficasPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GeneraciondeGraficasLabel = uilabel(app.GraficasPanel);
            app.GeneraciondeGraficasLabel.Text = 'Generación de Gráficas';
            app.TipodegraficaLabel = uilabel(app.GraficasPanel);
            app.TipodegraficaLabel.Text = 'Tipo de gráfica:';
            app.DropDown2 = uidropdown(app.GraficasPanel);
            app.DropDown2.Items = {'Histograma', 'Barras', 'Pastel'};
            app.DropDown2.Value = 'Histograma';
            app.GenerarGraficaButton = uibutton(app.GraficasPanel, 'push');
            app.GenerarGraficaButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarGraficaButtonPushed, true);
            app.GenerarGraficaButton.Text = 'Generar Gráfica';
            app.SeleccionarVariableDropDown_3Label = uilabel(app.GraficasPanel);
            app.SeleccionarVariableDropDown_3Label.Text = 'Seleccionar Variable:';
            app.SeleccionarVariableDropDown_3 = uidropdown(app.GraficasPanel);
            app.ProbabilidadesPanel = uipanel(app.UIFigure);
            app.ProbabilidadesPanel.Title = 'Probabilidades';
            app.ClculodeProbabilidadesLabel = uilabel(app.ProbabilidadesPanel);
            app.ClculodeProbabilidadesLabel.Text = 'Cálculo de Probabilidades';
            app.Label = uilabel(app.ProbabilidadesPanel);
            app.Label.Text = '';
            app.SeleccionelaVariableProbabilsticaDropDownLabel = uilabel(app.ProbabilidadesPanel);
            app.SeleccionelaVariableProbabilsticaDropDownLabel.Text = 'Seleccione la Variable Probabilística:';
            app.RespuestaProbabilidadDropDown = uidropdown(app.ProbabilidadesPanel);
            app.RespuestaProbabilidadDropDown.ValueChangedFcn = createCallbackFcn(app, @RespuestaProbabilidadDropDownValueChanged, true);
            app.CalcularButton_2 = uibutton(app.ProbabilidadesPanel, 'push');
            app.CalcularButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.CalcularButton_2.Text = 'Calcular';
            app.ResultadoLabel = uilabel(app.ProbabilidadesPanel);
            app.ResultadoLabel.Text = 'Resultado:';
            app.SeleccionarPreguntaLabel = uilabel(app.ProbabilidadesPanel);
            app.SeleccionarPreguntaLabel.Text = 'Seleccionar Pregunta';
            app.SeleccionelaVariableProbabilsticaDropDown = uidropdown(app.ProbabilidadesPanel);
            app.SeleccionelaVariableProbabilsticaDropDown.ValueChangedFcn = createCallbackFcn(app, @SeleccionelaVariableProbabilsticaDropDownValueChanged, true);
            app.DistribucionNormalPanel = uipanel(app.UIFigure);
            app.DistribucionNormalPanel.Title = 'Distribucion Normal';
            app.UIAxes2 = uiaxes(app.DistribucionNormalPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.AnlisisdeDistribucinNormalLabel = uilabel(app.DistribucionNormalPanel);
            app.AnlisisdeDistribucinNormalLabel.Text = 'Análisis de Distribución Normal';
            app.MediadeladistribucinNormalEditFieldLabel = uilabel(app.DistribucionNormalPanel);
            app.MediadeladistribucinNormalEditFieldLabel.Text = 'Media de la distribución Normal';
            app.MediadeladistribucinNormalEditField = uieditfield(app.DistribucionNormalPanel, 'numeric');
            app.DesviacinEstndardelaDistribucinEditFieldLabel = uilabel(app.DistribucionNormalPanel);
            app.DesviacinEstndardelaDistribucinEditFieldLabel.Text = 'Desviación Estándar de la Distribución:';
            app.DesviacinEstndardelaDistribucinEditField = uieditfield(app.DistribucionNormalPanel, 'numeric');
            app.ValorEstadsticoXEditFieldLabel = uilabel(app.DistribucionNormalPanel);
            app.ValorEstadsticoXEditFieldLabel.Text = 'Valor Estadístico X:';
            app.ValorEstadsticoXEditField = uieditfield(app.DistribucionNormalPanel, 'numeric');
            app.TipodedistribucionNormalButtonGroup = uibuttongroup(app.DistribucionNormalPanel);
            app.TipodedistribucionNormalButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TipodedistribucionNormalButtonGroupSelectionChanged, true);
            app.TipodedistribucionNormalButtonGroup.Title = 'Tipo de distribucion Normal';
            app.PXxButton = uiradiobutton(app.TipodedistribucionNormalButtonGroup);
            app.PXxButton.Text = 'P(X < x)';
            app.PXxButton.Value = true;
            app.PXxButton_2 = uiradiobutton(app.TipodedistribucionNormalButtonGroup);
            app.PXxButton_2.Text = 'P(X > x)';
            app.PaXbButton = uiradiobutton(app.TipodedistribucionNormalButtonGroup);
            app.PaXbButton.Text = 'P(a < X < b)';
            app.GenerarCurvadeDistribucinButton = uibutton(app.DistribucionNormalPanel, 'push');
            app.GenerarCurvadeDistribucinButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarCurvadeDistribucinButtonPushed, true);
            app.GenerarCurvadeDistribucinButton.Text = 'Generar Curva de Distribución';
            app.ResultadodeProbabilidadLabel = uilabel(app.DistribucionNormalPanel);
            app.ResultadodeProbabilidadLabel.Text = 'Resultado de Probabilidad:';
            app.SegundovalorEditFieldLabel = uilabel(app.DistribucionNormalPanel);
            app.SegundovalorEditFieldLabel.Text = 'Segundo valor';
            app.SegundoValorDistribucionEditField = uieditfield(app.DistribucionNormalPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProyectoEstadisticaII

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end